classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImageConvolutionTab           matlab.ui.container.Tab
        ProcessButton                 matlab.ui.control.Button
        InsertImageButton             matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        MaskEditField                 matlab.ui.control.EditField
        MaskEditFieldLabel            matlab.ui.control.Label
        ResultImageLabel              matlab.ui.control.Label
        OriginalImageLabel            matlab.ui.control.Label
        InputImage                    matlab.ui.control.Image
        ResultImage                   matlab.ui.control.Image
        LowPassFilteringTab           matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        SpatialTab                    matlab.ui.container.Tab
        FilterDropDown                matlab.ui.control.DropDown
        FilterDropDownLabel           matlab.ui.control.Label
        SigmaSpinner                  matlab.ui.control.Spinner
        SpinnerLabel                  matlab.ui.control.Label
        MaskDimensionSpinner          matlab.ui.control.Spinner
        MaskDimensionSpinnerLabel     matlab.ui.control.Label
        FrequencyTab                  matlab.ui.container.Tab
        PaddingLabel                  matlab.ui.control.Label
        PaddingCheckBox               matlab.ui.control.CheckBox
        FilterDropDown_2              matlab.ui.control.DropDown
        FilterDropDown_2Label         matlab.ui.control.Label
        NSpinner                      matlab.ui.control.Spinner
        nLabel                        matlab.ui.control.Label
        D0Spinner                     matlab.ui.control.Spinner
        D0SpinnerLabel                matlab.ui.control.Label
        ProcessButton_2               matlab.ui.control.Button
        InsertImageButton_2           matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        ResultImageLabel_2            matlab.ui.control.Label
        OriginalImageLabel_2          matlab.ui.control.Label
        InputImage_2                  matlab.ui.control.Image
        ResultImage_2                 matlab.ui.control.Image
        HighPassFilteringTab          matlab.ui.container.Tab
        FilterDropDown_3              matlab.ui.control.DropDown
        FilterDropDown_3Label         matlab.ui.control.Label
        NSpinner_2                    matlab.ui.control.Spinner
        nLabel_2                      matlab.ui.control.Label
        D0Spinner_2                   matlab.ui.control.Spinner
        D0Spinner_2Label              matlab.ui.control.Label
        PaddingLabel_2                matlab.ui.control.Label
        PaddingCheckBox_2             matlab.ui.control.CheckBox
        ProcessButton_3               matlab.ui.control.Button
        InsertImageButton_3           matlab.ui.control.Button
        Image_3                       matlab.ui.control.Image
        ResultImageLabel_3            matlab.ui.control.Label
        OriginalImageLabel_3          matlab.ui.control.Label
        InputImage_3                  matlab.ui.control.Image
        ResultImage_3                 matlab.ui.control.Image
        ImageBrighteningTab           matlab.ui.container.Tab
        NSpinner_3                    matlab.ui.control.Spinner
        nLabel_3                      matlab.ui.control.Label
        ProcessButton_4               matlab.ui.control.Button
        InsertImageButton_4           matlab.ui.control.Button
        Image_4                       matlab.ui.control.Image
        ResultImageLabel_4            matlab.ui.control.Label
        OriginalImageLabel_4          matlab.ui.control.Label
        InputImage_4                  matlab.ui.control.Image
        ResultImage_4                 matlab.ui.control.Image
        NoiseFilteringTab             matlab.ui.container.Tab
        InsertNoisyImageButton        matlab.ui.control.Button
        TabGroup3                     matlab.ui.container.TabGroup
        NoiseTab                      matlab.ui.container.Tab
        NoiseTypeDropDown             matlab.ui.control.DropDown
        NoiseTypeDropDownLabel        matlab.ui.control.Label
        VarianceSpinner               matlab.ui.control.Spinner
        nLabel_8                      matlab.ui.control.Label
        MeanSpinner                   matlab.ui.control.Spinner
        nLabel_7                      matlab.ui.control.Label
        NoiseOriginalImageButton      matlab.ui.control.Button
        NSpinner_4                    matlab.ui.control.Spinner
        nLabel_4                      matlab.ui.control.Label
        FilteringTab                  matlab.ui.container.Tab
        CenterExclusionRadiusSpinner  matlab.ui.control.Spinner
        CenterExclusionRadiusSpinnerLabel  matlab.ui.control.Label
        nSpinner                      matlab.ui.control.Spinner
        nSpinnerLabel                 matlab.ui.control.Label
        ThresholdFactorSpinner        matlab.ui.control.Spinner
        ThresholdFactorSpinnerLabel   matlab.ui.control.Label
        D0Spinner_3                   matlab.ui.control.Spinner
        D0Spinner_3Label              matlab.ui.control.Label
        DSpinner                      matlab.ui.control.Spinner
        dLabel                        matlab.ui.control.Label
        FilterDropDown_4              matlab.ui.control.DropDown
        FilterDropDown_4Label         matlab.ui.control.Label
        QSpinner                      matlab.ui.control.Spinner
        QLabel                        matlab.ui.control.Label
        MaskDimensionSpinner_2        matlab.ui.control.Spinner
        MaskDimensionSpinner_2Label   matlab.ui.control.Label
        Image_6                       matlab.ui.control.Image
        NoisyImageLabel               matlab.ui.control.Label
        NoisyImage                    matlab.ui.control.Image
        ProcessButton_5               matlab.ui.control.Button
        InsertImageButton_5           matlab.ui.control.Button
        Image_5                       matlab.ui.control.Image
        ResultImageLabel_5            matlab.ui.control.Label
        OriginalImageLabel_5          matlab.ui.control.Label
        InputImage_5                  matlab.ui.control.Image
        ResultImage_5                 matlab.ui.control.Image
        ImageDeblurringTab            matlab.ui.container.Tab
        InsertBlurryImageButton       matlab.ui.control.Button
        TabGroup3_2                   matlab.ui.container.TabGroup
        MotionBlurringTab             matlab.ui.container.Tab
        LengthSpinner                 matlab.ui.control.Spinner
        nLabel_6                      matlab.ui.control.Label
        BlurOriginalImageButton       matlab.ui.control.Button
        ThetaSpinner                  matlab.ui.control.Spinner
        nLabel_5                      matlab.ui.control.Label
        DeblurringTab                 matlab.ui.container.Tab
        NoiseVarianceSpinner          matlab.ui.control.Spinner
        NoiseVarianceSpinnerLabel     matlab.ui.control.Label
        MaskDimensionSpinner_3        matlab.ui.control.Spinner
        MaskDimensionSpinner_3Label   matlab.ui.control.Label
        SigmaSpinner_2                matlab.ui.control.Spinner
        SpinnerLabel_2                matlab.ui.control.Label
        LengthSpinner_2               matlab.ui.control.Spinner
        LengthSpinner_2Label          matlab.ui.control.Label
        ThetaSpinner_2                matlab.ui.control.Spinner
        ThetaSpinner_2Label           matlab.ui.control.Label
        BlurDropDown                  matlab.ui.control.DropDown
        BlurDropDownLabel             matlab.ui.control.Label
        Image_8                       matlab.ui.control.Image
        BlurryImageLabel              matlab.ui.control.Label
        NoisyImage_2                  matlab.ui.control.Image
        ProcessButton_6               matlab.ui.control.Button
        InsertImageButton_6           matlab.ui.control.Button
        Image_7                       matlab.ui.control.Image
        ResultImageLabel_6            matlab.ui.control.Label
        OriginalImageLabel_6          matlab.ui.control.Label
        InputImage_6                  matlab.ui.control.Image
        ResultImage_6                 matlab.ui.control.Image
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        originalImage
    end
    
    properties (Access = private)
    end
    
    properties (Access = public)
        noisyImage
    end
    
    methods (Access = private)
        
        function InputImageEvent(app, imageContainer)
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            app.originalImage = imread(filepath);
            
            % Reset all UIs
            app.InputImage.ImageSource = '';
            app.InputImage_2.ImageSource = '';
            app.InputImage_3.ImageSource = '';
            app.InputImage_4.ImageSource = '';
            app.InputImage_5.ImageSource = '';
            app.InputImage_6.ImageSource = '';

            app.NoisyImage.ImageSource = '';
            app.NoisyImage_2.ImageSource = '';

            app.ResultImage.ImageSource = '';
            app.ResultImage_2.ImageSource = '';
            app.ResultImage_3.ImageSource = '';
            app.ResultImage_4.ImageSource = '';
            app.ResultImage_5.ImageSource = '';
            app.ResultImage_6.ImageSource = '';
            %app.ResultImage.ImageSource = '';
            %app.InputImage.ImageSource = '';

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                
                % Use tempname to generate a complete file path
                tempfile = [tempname, '.png'];  % Correctly append '.png' to tempname
                
                % Write the image to the temporary file
                imwrite(img, tempfile);
                
                % Update the Image component's ImageSource property
                imageContainer.ImageSource = tempfile;  % Use the complete file path
            else
                % Use the selected file directly if it's already a supported format
                imageContainer.ImageSource = filepath;
            end 
        end

        function InputNoisyImageEvent(app, imageContainer)
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            app.noisyImage = imread(filepath);
            
            % Reset all UIs
            app.InputImage.ImageSource = '';
            app.InputImage_2.ImageSource = '';
            app.InputImage_3.ImageSource = '';
            app.InputImage_4.ImageSource = '';
            app.InputImage_5.ImageSource = '';
            app.InputImage_6.ImageSource = '';

            app.ResultImage.ImageSource = '';
            app.ResultImage_2.ImageSource = '';
            app.ResultImage_3.ImageSource = '';
            app.ResultImage_4.ImageSource = '';
            app.ResultImage_5.ImageSource = '';
            app.ResultImage_6.ImageSource = '';
            %app.ResultImage.ImageSource = '';
            %app.InputImage.ImageSource = '';

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                
                % Use tempname to generate a complete file path
                tempfile = [tempname, '.png'];  % Correctly append '.png' to tempname
                
                % Write the image to the temporary file
                imwrite(img, tempfile);
                
                % Update the Image component's ImageSource property
                imageContainer.ImageSource = tempfile;  % Use the complete file path
            else
                % Use the selected file directly if it's already a supported format
                imageContainer.ImageSource = filepath;
            end 
        end

        function PostProcessImage(app, image, resultContainer)
            tempfile = [tempname, '.png'];
            imwrite(image, tempfile);
            
            result = image;
            if ndims(image) ~= 3
                result = cat(3, image, image, image);
            end

            resultContainer.ImageSource = result;
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertImageButton
        function InputImageButtonPushed(app, event)
            app.InputImageEvent(app.InputImage);
        end

        % Callback function
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;

            if isequal(value, "Image Enhancement")
                app.BrighteningCheckBox.Visible = "on";
                app.MaskEditField.Visible = "on";
                app.NegativeCheckBox.Visible = "on";
                app.LogTransformationCheckBox.Visible = "on";
                app.PowerTransformationCheckBox.Visible = "on";
                app.ContrastStretchingCheckBox.Visible = "on";
                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.bEditField.Visible = "on";
                app.bEditFieldLabel.Visible = "on";
                app.cEditField.Visible = "on";
                app.cEditFieldLabel.Visible = "on";
                app.cEditField_2.Visible = "on";
                app.cEditField_2Label.Visible = "on";
                app.InputImage.Visible = "off";
                app.InputReferenceImageButton.Visible = "off";
                app.TabGroup_3.Visible = "off";
            end

            if isequal(value, "Histogram Equalization")
                app.BrighteningCheckBox.Visible = "off";
                app.MaskEditField.Visible = "off";
                app.NegativeCheckBox.Visible = "off";
                app.LogTransformationCheckBox.Visible = "off";
                app.PowerTransformationCheckBox.Visible = "off";
                app.ContrastStretchingCheckBox.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.InputImage.Visible = "off";
                app.InputReferenceImageButton.Visible = "off";
                app.TabGroup_3.Visible = "off";
            end

            if isequal(value, "Histogram Specification")
                app.BrighteningCheckBox.Visible = "off";
                app.MaskEditField.Visible = "off";
                app.NegativeCheckBox.Visible = "off";
                app.LogTransformationCheckBox.Visible = "off";
                app.PowerTransformationCheckBox.Visible = "off";
                app.ContrastStretchingCheckBox.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.InputImage.Visible = "on";
                app.InputReferenceImageButton.Visible = "on";
                app.TabGroup_3.Visible = "on";
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            if isequal(app.MaskEditField.Value, '')
                uialert(app.UIFigure, 'No input mask detected', 'No Input');
                return;
            end

            processedImg = app.originalImage;
            
            mask = str2num(app.MaskEditField.Value);
            processedImg = uint8(convolution(processedImg, mask));

            app.PostProcessImage(processedImg, app.ResultImage);
        end

        % Callback function
        function InputReferenceImageButtonPushed(app, event)

        end

        % Button pushed function: InsertImageButton_2
        function InsertImageButton_2Pushed(app, event)
            app.InputImageEvent(app.InputImage_2);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.originalImage = [];
            app.noisyImage = [];
            
            app.InputImage.ImageSource = '';
            app.InputImage_2.ImageSource = '';
            app.InputImage_3.ImageSource = '';
            app.InputImage_4.ImageSource = '';
            app.InputImage_5.ImageSource = '';
            app.InputImage_6.ImageSource = '';

            app.NoisyImage.ImageSource = '';
            app.NoisyImage_2.ImageSource = '';
            
            app.ResultImage.ImageSource = '';
            app.ResultImage_2.ImageSource = '';
            app.ResultImage_3.ImageSource = '';
            app.ResultImage_4.ImageSource = '';
            app.ResultImage_5.ImageSource = '';
            app.ResultImage_6.ImageSource = '';
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            processedImg = app.originalImage;
            
            if isequal(app.TabGroup2.SelectedTab.Title, 'Spatial')
                dim = app.MaskDimensionSpinner.Value;
                if isequal(app.FilterDropDown.Value, 'Mean Filter')
                    processedImg = meanFilter(processedImg, dim);
                else
                    processedImg = gaussianFilter(processedImg, dim, app.SigmaSpinner.Value);
                end
            else
                d0 = app.D0Spinner.Value;
                padding = app.PaddingCheckBox.Value;
                if isequal(app.FilterDropDown_2.Value, 'ILPF')
                    %disp("ILPF");
                    processedImg = frequencyLPF(processedImg, 'ideal', d0, padding);
                elseif isequal(app.FilterDropDown.Value, 'GLPF')
                    %disp("GLPF");
                    processedImg = frequencyLPF(processedImg, 'gaussian', d0, padding);
                else
                    %disp("HLPF");
                    processedImg = frequencyLPF(processedImg, 'butterworth', d0, padding, app.NSpinner.Value);
                end
            end

            app.PostProcessImage(processedImg, app.ResultImage_2);
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
            if isequal(value, "Gaussian Filter")
                app.SigmaSpinner.Enable = true;
                app.SpinnerLabel.Enable = true;
            else
                app.SigmaSpinner.Enable = false;
                app.SpinnerLabel.Enable = false;
            end
        end

        % Value changed function: FilterDropDown_2
        function FilterDropDown_2ValueChanged(app, event)
            value = app.FilterDropDown_2.Value;
            
            if isequal(value, "BLPF")
                app.NSpinner.Enable = true;
                app.nLabel.Enable = true;
            else
                app.NSpinner.Enable = false;
                app.nLabel.Enable = false;
            end
        end

        % Button pushed function: InsertImageButton_3
        function InsertImageButton_3Pushed(app, event)
            app.InputImageEvent(app.InputImage_3);
        end

        % Button pushed function: ProcessButton_3
        function ProcessButton_3Pushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            processedImg = app.originalImage;

            d0 = app.D0Spinner_2.Value;
            padding = app.PaddingCheckBox_2.Value;
            if isequal(app.FilterDropDown_3.Value, 'IHPF')
                processedImg = frequencyHPF(processedImg, 'ideal', d0, padding);
            elseif isequal(app.FilterDropDown.Value, 'GHPF')
                processedImg = frequencyHPF(processedImg, 'gaussian', d0, padding);
            else
                processedImg = frequencyHPF(processedImg, 'butterworth', d0, padding, app.NSpinner_2.Value);
            end

            app.PostProcessImage(processedImg, app.ResultImage_3);
        end

        % Value changed function: FilterDropDown_3
        function FilterDropDown_3ValueChanged(app, event)
            value = app.FilterDropDown_3.Value;
            
            if isequal(value, 'BHPF')
                app.NSpinner_2.Enable = true;
                app.nLabel_2.Enable = true;
            else
                app.NSpinner_2.Enable = false;
                app.nLabel_2.Enable = false;
            end
        end

        % Button pushed function: InsertImageButton_4
        function InsertImageButton_4Pushed(app, event)
            app.InputImageEvent(app.InputImage_4);
        end

        % Button pushed function: ProcessButton_4
        function ProcessButton_4Pushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            processedImg = app.originalImage;
            processedImg = brighteningFilter(processedImg, app.NSpinner_3.Value);

            app.PostProcessImage(processedImg, app.ResultImage_4);
        end

        % Button pushed function: NoiseOriginalImageButton
        function NoiseOriginalImageButtonPushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            app.noisyImage = app.originalImage;
            if isequal(app.NoiseTypeDropDown.Value, "Salt & Pepper")
                app.noisyImage = saltPepperNoise(app.noisyImage, app.NSpinner_4.Value);
            else
                app.noisyImage = gaussianNoise(app.noisyImage, app.MeanSpinner.Value, app.VarianceSpinner.Value);
            end

            app.PostProcessImage(app.noisyImage, app.NoisyImage);
        end

        % Button pushed function: InsertImageButton_5
        function InsertImageButton_5Pushed(app, event)
            app.InputImageEvent(app.InputImage_5);
        end

        % Button pushed function: ProcessButton_5
        function ProcessButton_5Pushed(app, event)
            if isequal(app.noisyImage, [])
                uialert(app.UIFigure, 'No noisy image detected', 'No Input');
                return;
            end

            processedImg = app.noisyImage;
            maskDim = app.MaskDimensionSpinner_2.Value;
            switch app.FilterDropDown_4.Value
                case 'Arithmetic Mean'
                    processedImg = meanFilters(processedImg, maskDim, 'arithmetic');
                case 'Geometric Mean'
                    processedImg = meanFilters(processedImg, maskDim, 'geometric');
                case 'Harmonic Mean'
                    processedImg = meanFilters(processedImg, maskDim, 'harmonic');
                case 'Contraharmonic Mean'
                    processedImg = meanFilters(processedImg, maskDim, 'contraharmonic', app.QSpinner.Value);
                case 'Max'
                    processedImg = orderStatisticFilters(processedImg, maskDim, 'max');
                case 'Min'
                    processedImg = orderStatisticFilters(processedImg, maskDim, 'min');
                case 'Median'
                    processedImg = orderStatisticFilters(processedImg, maskDim, 'median');
                case 'Midpoint'
                    processedImg = orderStatisticFilters(processedImg, maskDim, 'midpoint');
                case 'Alpha-trimmed'
                    processedImg = orderStatisticFilters(processedImg, maskDim, 'alpha-trimmed');
                otherwise
                    processedImg = notchRejectFilter(processedImg, app.D0Spinner_3.Value, app.ThresholdFactorSpinner.Value, app.CenterExclusionRadiusSpinner.Value, app.nSpinner.Value);
            end
            
            app.PostProcessImage(processedImg, app.ResultImage_5);
        end

        % Value changed function: FilterDropDown_4
        function FilterDropDown_4ValueChanged(app, event)
            value = app.FilterDropDown_4.Value;
            
            app.MaskDimensionSpinner_2.Enable = true;
            app.MaskDimensionSpinner_2Label.Enable = true;
            app.QSpinner.Enable = false;
            app.QLabel.Enable = false;
            app.DSpinner.Enable = false;
            app.dLabel.Enable = false;
            app.D0Spinner_3.Enable = false;
            app.D0Spinner_3Label.Enable = false;
            app.ThresholdFactorSpinner.Enable = false;
            app.ThresholdFactorSpinnerLabel.Enable = false;
            app.CenterExclusionRadiusSpinner.Enable = false;
            app.CenterExclusionRadiusSpinnerLabel.Enable = false;
            app.nSpinner.Enable = false;
            app.nSpinnerLabel.Enable = false;

            if isequal(value, 'Contraharmonic Mean')
                app.QSpinner.Enable = true;
                app.QLabel.Enable = true;
            elseif isequal(value, 'Alpha-trimmed')
                app.DSpinner.Enable = true;
                app.dLabel.Enable = true;
            elseif isequal(value, 'Notch')
                app.MaskDimensionSpinner_2.Enable = false;
                app.MaskDimensionSpinner_2Label.Enable = false;
                app.D0Spinner_3.Enable = true;
                app.D0Spinner_3Label.Enable = true;
                app.ThresholdFactorSpinner.Enable = true;
                app.ThresholdFactorSpinnerLabel.Enable = true;
                app.CenterExclusionRadiusSpinner.Enable = true;
                app.CenterExclusionRadiusSpinnerLabel.Enable = true;
                app.nSpinner.Enable = true;
                app.nSpinnerLabel.Enable = true;
            end
        end

        % Button pushed function: InsertNoisyImageButton
        function InsertNoisyImageButtonPushed(app, event)
            app.InputNoisyImageEvent(app.NoisyImage);
        end

        % Button pushed function: InsertImageButton_6
        function InsertImageButton_6Pushed(app, event)
            app.InputImageEvent(app.InputImage_6);
        end

        % Button pushed function: InsertBlurryImageButton
        function InsertBlurryImageButtonPushed(app, event)
            app.InputNoisyImageEvent(app.NoisyImage_2);
        end

        % Button pushed function: BlurOriginalImageButton
        function BlurOriginalImageButtonPushed(app, event)
            if isequal(app.originalImage, [])
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end

            app.noisyImage = app.originalImage;
            app.noisyImage = motionBlur(double(app.noisyImage), app.LengthSpinner.Value, app.ThetaSpinner.Value);

            app.PostProcessImage(uint8(app.noisyImage), app.NoisyImage_2);
        end

        % Value changed function: BlurDropDown
        function BlurDropDownValueChanged(app, event)
            value = app.BlurDropDown.Value;
            
            if isequal(value, 'Gaussian')
                app.LengthSpinner_2.Enable = false;
                app.LengthSpinner_2Label.Enable = false;
                app.ThetaSpinner_2.Enable = false;
                app.ThetaSpinner_2Label.Enable = false;

                app.MaskDimensionSpinner_3.Enable = true;
                app.MaskDimensionSpinner_3Label.Enable = true;
                app.SigmaSpinner_2.Enable = true;
                app.SpinnerLabel_2.Enable = true;
            else
                app.LengthSpinner_2.Enable = true;
                app.LengthSpinner_2Label.Enable = true;
                app.ThetaSpinner_2.Enable = true;
                app.ThetaSpinner_2Label.Enable = true;

                app.MaskDimensionSpinner_3.Enable = false;
                app.MaskDimensionSpinner_3Label.Enable = false;
                app.SigmaSpinner_2.Enable = false;
                app.SpinnerLabel_2.Enable = false;
            end
        end

        % Button pushed function: ProcessButton_6
        function ProcessButton_6Pushed(app, event)
            if isequal(app.noisyImage, [])
                uialert(app.UIFigure, 'No blurry image detected', 'No Input');
                return;
            end

            processedImg = app.noisyImage;        
            dblImg = im2double(processedImg);
            nsr = app.NoiseVarianceSpinner.Value / var(dblImg(:));
            switch app.BlurDropDown.Value
                case 'Motion'
                    %disp('Motion');
                    psf = fspecial('motion', app.LengthSpinner_2.Value, app.ThetaSpinner_2.Value);
                    processedImg = uint8(deconvolution(im2double(processedImg), psf, nsr));
                otherwise
                    psf = fspecial('gaussian', app.MaskDimensionSpinner_3.Value, app.SigmaSpinner_2.Value);
                    processedImg = deconvolution(im2double(processedImg), psf, nsr);
            end
            app.PostProcessImage(processedImg, app.ResultImage_6);
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            value = app.NoiseTypeDropDown.Value;
            
            if isequal(value, 'Salt & Pepper')
                app.NSpinner_4.Enable = true;
                app.nLabel_4.Enable = true;

                app.MeanSpinner.Enable = false;
                app.nLabel_7.Enable = false;
                app.VarianceSpinner.Enable = false;
                app.nLabel_8.Enable = false;
            else
                app.NSpinner_4.Enable = false;
                app.nLabel_4.Enable = false;

                app.MeanSpinner.Enable = true;
                app.nLabel_7.Enable = true;
                app.VarianceSpinner.Enable = true;
                app.nLabel_8.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImageConvolutionTab = uitab(app.TabGroup);
            app.ImageConvolutionTab.Title = 'Image Convolution';
            app.ResultImage = uiimage(app.ImageConvolutionTab);
            app.InputImage = uiimage(app.ImageConvolutionTab);
            app.OriginalImageLabel = uilabel(app.ImageConvolutionTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ResultImageLabel = uilabel(app.ImageConvolutionTab);
            app.ResultImageLabel.Text = 'Result Image';
            app.MaskEditFieldLabel = uilabel(app.ImageConvolutionTab);
            app.MaskEditFieldLabel.Text = 'Mask';
            app.MaskEditField = uieditfield(app.ImageConvolutionTab, 'text');
            app.Image = uiimage(app.ImageConvolutionTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton = uibutton(app.ImageConvolutionTab, 'push');
            app.InsertImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InsertImageButton.Text = 'Insert Image';
            app.ProcessButton = uibutton(app.ImageConvolutionTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.LowPassFilteringTab = uitab(app.TabGroup);
            app.LowPassFilteringTab.Title = 'Low Pass Filtering';
            app.ResultImage_2 = uiimage(app.LowPassFilteringTab);
            app.InputImage_2 = uiimage(app.LowPassFilteringTab);
            app.OriginalImageLabel_2 = uilabel(app.LowPassFilteringTab);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.ResultImageLabel_2 = uilabel(app.LowPassFilteringTab);
            app.ResultImageLabel_2.Text = 'Result Image';
            app.Image_2 = uiimage(app.LowPassFilteringTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton_2 = uibutton(app.LowPassFilteringTab, 'push');
            app.InsertImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_2Pushed, true);
            app.InsertImageButton_2.Text = 'Insert Image';
            app.ProcessButton_2 = uibutton(app.LowPassFilteringTab, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.TabGroup2 = uitabgroup(app.LowPassFilteringTab);
            app.SpatialTab = uitab(app.TabGroup2);
            app.SpatialTab.Title = 'Spatial';
            app.MaskDimensionSpinnerLabel = uilabel(app.SpatialTab);
            app.MaskDimensionSpinnerLabel.Text = 'Mask Dimension';
            app.MaskDimensionSpinner = uispinner(app.SpatialTab);
            app.MaskDimensionSpinner.Step = 2;
            app.MaskDimensionSpinner.Limits = [3 Inf];
            app.MaskDimensionSpinner.Value = 3;
            app.SpinnerLabel = uilabel(app.SpatialTab);
            app.SpinnerLabel.Enable = 'off';
            app.SpinnerLabel.Text = 'σ';
            app.SigmaSpinner = uispinner(app.SpatialTab);
            app.SigmaSpinner.Step = 0.01;
            app.SigmaSpinner.Limits = [0.01 Inf];
            app.SigmaSpinner.Enable = 'off';
            app.SigmaSpinner.Value = 3;
            app.FilterDropDownLabel = uilabel(app.SpatialTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.SpatialTab);
            app.FilterDropDown.Items = {'Mean Filter', 'Gaussian Filter'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'Mean Filter';
            app.FrequencyTab = uitab(app.TabGroup2);
            app.FrequencyTab.Title = 'Frequency';
            app.D0SpinnerLabel = uilabel(app.FrequencyTab);
            app.D0SpinnerLabel.Text = 'D0';
            app.D0Spinner = uispinner(app.FrequencyTab);
            app.D0Spinner.Limits = [1 Inf];
            app.D0Spinner.Value = 50;
            app.nLabel = uilabel(app.FrequencyTab);
            app.nLabel.Enable = 'off';
            app.nLabel.Text = 'n';
            app.NSpinner = uispinner(app.FrequencyTab);
            app.NSpinner.Limits = [1 Inf];
            app.NSpinner.Enable = 'off';
            app.NSpinner.Value = 1;
            app.FilterDropDown_2Label = uilabel(app.FrequencyTab);
            app.FilterDropDown_2Label.Text = 'Filter';
            app.FilterDropDown_2 = uidropdown(app.FrequencyTab);
            app.FilterDropDown_2.Items = {'ILPF', 'GLPF', 'BLPF'};
            app.FilterDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FilterDropDown_2ValueChanged, true);
            app.FilterDropDown_2.Value = 'ILPF';
            app.PaddingCheckBox = uicheckbox(app.FrequencyTab);
            app.PaddingCheckBox.Text = 'Padding';
            app.PaddingLabel = uilabel(app.FrequencyTab);
            app.PaddingLabel.Text = '* Image will be padded 2x it''s original size';
            app.HighPassFilteringTab = uitab(app.TabGroup);
            app.HighPassFilteringTab.Title = 'High Pass Filtering';
            app.ResultImage_3 = uiimage(app.HighPassFilteringTab);
            app.InputImage_3 = uiimage(app.HighPassFilteringTab);
            app.OriginalImageLabel_3 = uilabel(app.HighPassFilteringTab);
            app.OriginalImageLabel_3.Text = 'Original Image';
            app.ResultImageLabel_3 = uilabel(app.HighPassFilteringTab);
            app.ResultImageLabel_3.Text = 'Result Image';
            app.Image_3 = uiimage(app.HighPassFilteringTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton_3 = uibutton(app.HighPassFilteringTab, 'push');
            app.InsertImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_3Pushed, true);
            app.InsertImageButton_3.Text = 'Insert Image';
            app.ProcessButton_3 = uibutton(app.HighPassFilteringTab, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_3Pushed, true);
            app.ProcessButton_3.Text = 'Process';
            app.PaddingCheckBox_2 = uicheckbox(app.HighPassFilteringTab);
            app.PaddingCheckBox_2.Text = 'Padding';
            app.PaddingLabel_2 = uilabel(app.HighPassFilteringTab);
            app.PaddingLabel_2.Text = '* Image will be padded 2x it''s original size';
            app.D0Spinner_2Label = uilabel(app.HighPassFilteringTab);
            app.D0Spinner_2Label.Text = 'D0';
            app.D0Spinner_2 = uispinner(app.HighPassFilteringTab);
            app.D0Spinner_2.Limits = [1 Inf];
            app.D0Spinner_2.Value = 50;
            app.nLabel_2 = uilabel(app.HighPassFilteringTab);
            app.nLabel_2.Enable = 'off';
            app.nLabel_2.Text = 'n';
            app.NSpinner_2 = uispinner(app.HighPassFilteringTab);
            app.NSpinner_2.Limits = [1 Inf];
            app.NSpinner_2.Enable = 'off';
            app.NSpinner_2.Value = 1;
            app.FilterDropDown_3Label = uilabel(app.HighPassFilteringTab);
            app.FilterDropDown_3Label.Text = 'Filter';
            app.FilterDropDown_3 = uidropdown(app.HighPassFilteringTab);
            app.FilterDropDown_3.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.FilterDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FilterDropDown_3ValueChanged, true);
            app.FilterDropDown_3.Value = 'IHPF';
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.ResultImage_4 = uiimage(app.ImageBrighteningTab);
            app.InputImage_4 = uiimage(app.ImageBrighteningTab);
            app.OriginalImageLabel_4 = uilabel(app.ImageBrighteningTab);
            app.OriginalImageLabel_4.Text = 'Original Image';
            app.ResultImageLabel_4 = uilabel(app.ImageBrighteningTab);
            app.ResultImageLabel_4.Text = 'Result Image';
            app.Image_4 = uiimage(app.ImageBrighteningTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton_4 = uibutton(app.ImageBrighteningTab, 'push');
            app.InsertImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_4Pushed, true);
            app.InsertImageButton_4.Text = 'Insert Image';
            app.ProcessButton_4 = uibutton(app.ImageBrighteningTab, 'push');
            app.ProcessButton_4.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_4Pushed, true);
            app.ProcessButton_4.Text = 'Process';
            app.nLabel_3 = uilabel(app.ImageBrighteningTab);
            app.nLabel_3.Text = 'n';
            app.NSpinner_3 = uispinner(app.ImageBrighteningTab);
            app.NSpinner_3.Limits = [1 Inf];
            app.NSpinner_3.Value = 1;
            app.NoiseFilteringTab = uitab(app.TabGroup);
            app.NoiseFilteringTab.Title = 'Noise Filtering';
            app.ResultImage_5 = uiimage(app.NoiseFilteringTab);
            app.InputImage_5 = uiimage(app.NoiseFilteringTab);
            app.OriginalImageLabel_5 = uilabel(app.NoiseFilteringTab);
            app.OriginalImageLabel_5.Text = 'Original Image';
            app.ResultImageLabel_5 = uilabel(app.NoiseFilteringTab);
            app.ResultImageLabel_5.Text = 'Result Image';
            app.Image_5 = uiimage(app.NoiseFilteringTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton_5 = uibutton(app.NoiseFilteringTab, 'push');
            app.InsertImageButton_5.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_5Pushed, true);
            app.InsertImageButton_5.Text = 'Insert Image';
            app.ProcessButton_5 = uibutton(app.NoiseFilteringTab, 'push');
            app.ProcessButton_5.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_5Pushed, true);
            app.ProcessButton_5.Text = 'Process';
            app.NoisyImage = uiimage(app.NoiseFilteringTab);
            app.NoisyImageLabel = uilabel(app.NoiseFilteringTab);
            app.NoisyImageLabel.Text = 'Noisy Image';
            app.Image_6 = uiimage(app.NoiseFilteringTab);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.TabGroup3 = uitabgroup(app.NoiseFilteringTab);
            app.NoiseTab = uitab(app.TabGroup3);
            app.NoiseTab.Title = 'Noise';
            app.nLabel_4 = uilabel(app.NoiseTab);
            app.nLabel_4.Text = 'Noise Density';
            app.NSpinner_4 = uispinner(app.NoiseTab);
            app.NSpinner_4.Step = 0.01;
            app.NSpinner_4.Limits = [0.01 Inf];
            app.NSpinner_4.Value = 0.05;
            app.NoiseOriginalImageButton = uibutton(app.NoiseTab, 'push');
            app.NoiseOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseOriginalImageButtonPushed, true);
            app.NoiseOriginalImageButton.Text = 'Noise Original Image';
            app.nLabel_7 = uilabel(app.NoiseTab);
            app.nLabel_7.Enable = 'off';
            app.nLabel_7.Text = 'Mean';
            app.MeanSpinner = uispinner(app.NoiseTab);
            app.MeanSpinner.Step = 0.01;
            app.MeanSpinner.Limits = [0 Inf];
            app.MeanSpinner.Enable = 'off';
            app.nLabel_8 = uilabel(app.NoiseTab);
            app.nLabel_8.Enable = 'off';
            app.nLabel_8.Text = 'Variance';
            app.VarianceSpinner = uispinner(app.NoiseTab);
            app.VarianceSpinner.Step = 0.01;
            app.VarianceSpinner.Limits = [0.01 Inf];
            app.VarianceSpinner.Enable = 'off';
            app.VarianceSpinner.Value = 0.01;
            app.NoiseTypeDropDownLabel = uilabel(app.NoiseTab);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.NoiseTab);
            app.NoiseTypeDropDown.Items = {'Salt & Pepper', 'Gaussian'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'Salt & Pepper';
            app.FilteringTab = uitab(app.TabGroup3);
            app.FilteringTab.Title = 'Filtering';
            app.MaskDimensionSpinner_2Label = uilabel(app.FilteringTab);
            app.MaskDimensionSpinner_2Label.Text = 'Mask Dimension';
            app.MaskDimensionSpinner_2 = uispinner(app.FilteringTab);
            app.MaskDimensionSpinner_2.Step = 2;
            app.MaskDimensionSpinner_2.Limits = [3 Inf];
            app.MaskDimensionSpinner_2.Value = 3;
            app.QLabel = uilabel(app.FilteringTab);
            app.QLabel.Enable = 'off';
            app.QLabel.Text = 'Q';
            app.QSpinner = uispinner(app.FilteringTab);
            app.QSpinner.Step = 0.01;
            app.QSpinner.Enable = 'off';
            app.FilterDropDown_4Label = uilabel(app.FilteringTab);
            app.FilterDropDown_4Label.Text = 'Filter';
            app.FilterDropDown_4 = uidropdown(app.FilteringTab);
            app.FilterDropDown_4.Items = {'Arithmetic Mean', 'Geometric Mean', 'Harmonic Mean', 'Contraharmonic Mean', 'Max', 'Min', 'Median', 'Midpoint', 'Alpha-trimmed', 'Notch'};
            app.FilterDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FilterDropDown_4ValueChanged, true);
            app.FilterDropDown_4.Value = 'Arithmetic Mean';
            app.dLabel = uilabel(app.FilteringTab);
            app.dLabel.Enable = 'off';
            app.dLabel.Text = 'd';
            app.DSpinner = uispinner(app.FilteringTab);
            app.DSpinner.Limits = [1 Inf];
            app.DSpinner.Enable = 'off';
            app.DSpinner.Value = 3;
            app.D0Spinner_3Label = uilabel(app.FilteringTab);
            app.D0Spinner_3Label.Enable = 'off';
            app.D0Spinner_3Label.Text = 'D0';
            app.D0Spinner_3 = uispinner(app.FilteringTab);
            app.D0Spinner_3.Limits = [0 Inf];
            app.D0Spinner_3.Enable = 'off';
            app.D0Spinner_3.Value = 10;
            app.ThresholdFactorSpinnerLabel = uilabel(app.FilteringTab);
            app.ThresholdFactorSpinnerLabel.Enable = 'off';
            app.ThresholdFactorSpinnerLabel.Text = 'Threshold Factor';
            app.ThresholdFactorSpinner = uispinner(app.FilteringTab);
            app.ThresholdFactorSpinner.Step = 0.01;
            app.ThresholdFactorSpinner.Limits = [0 Inf];
            app.ThresholdFactorSpinner.Enable = 'off';
            app.ThresholdFactorSpinner.Value = 0.72;
            app.nSpinnerLabel = uilabel(app.FilteringTab);
            app.nSpinnerLabel.Enable = 'off';
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.FilteringTab);
            app.nSpinner.Limits = [0 Inf];
            app.nSpinner.Enable = 'off';
            app.nSpinner.Value = 2;
            app.CenterExclusionRadiusSpinnerLabel = uilabel(app.FilteringTab);
            app.CenterExclusionRadiusSpinnerLabel.Enable = 'off';
            app.CenterExclusionRadiusSpinnerLabel.Text = 'Center Exclusion Radius';
            app.CenterExclusionRadiusSpinner = uispinner(app.FilteringTab);
            app.CenterExclusionRadiusSpinner.Limits = [0 Inf];
            app.CenterExclusionRadiusSpinner.Enable = 'off';
            app.CenterExclusionRadiusSpinner.Value = 20;
            app.InsertNoisyImageButton = uibutton(app.NoiseFilteringTab, 'push');
            app.InsertNoisyImageButton.ButtonPushedFcn = createCallbackFcn(app, @InsertNoisyImageButtonPushed, true);
            app.InsertNoisyImageButton.Text = 'Insert Noisy Image';
            app.ImageDeblurringTab = uitab(app.TabGroup);
            app.ImageDeblurringTab.Title = 'Image Deblurring';
            app.ResultImage_6 = uiimage(app.ImageDeblurringTab);
            app.InputImage_6 = uiimage(app.ImageDeblurringTab);
            app.OriginalImageLabel_6 = uilabel(app.ImageDeblurringTab);
            app.OriginalImageLabel_6.Text = 'Original Image';
            app.ResultImageLabel_6 = uilabel(app.ImageDeblurringTab);
            app.ResultImageLabel_6.Text = 'Result Image';
            app.Image_7 = uiimage(app.ImageDeblurringTab);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.InsertImageButton_6 = uibutton(app.ImageDeblurringTab, 'push');
            app.InsertImageButton_6.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_6Pushed, true);
            app.InsertImageButton_6.Text = 'Insert Image';
            app.ProcessButton_6 = uibutton(app.ImageDeblurringTab, 'push');
            app.ProcessButton_6.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_6Pushed, true);
            app.ProcessButton_6.Text = 'Process';
            app.NoisyImage_2 = uiimage(app.ImageDeblurringTab);
            app.BlurryImageLabel = uilabel(app.ImageDeblurringTab);
            app.BlurryImageLabel.Text = 'Blurry Image';
            app.Image_8 = uiimage(app.ImageDeblurringTab);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'ui', 'right_arrow.png');
            app.TabGroup3_2 = uitabgroup(app.ImageDeblurringTab);
            app.MotionBlurringTab = uitab(app.TabGroup3_2);
            app.MotionBlurringTab.Title = 'Motion Blurring';
            app.nLabel_5 = uilabel(app.MotionBlurringTab);
            app.nLabel_5.Text = 'Theta';
            app.ThetaSpinner = uispinner(app.MotionBlurringTab);
            app.ThetaSpinner.Step = 0.01;
            app.ThetaSpinner.Value = 45;
            app.BlurOriginalImageButton = uibutton(app.MotionBlurringTab, 'push');
            app.BlurOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @BlurOriginalImageButtonPushed, true);
            app.BlurOriginalImageButton.Text = 'Blur Original Image';
            app.nLabel_6 = uilabel(app.MotionBlurringTab);
            app.nLabel_6.Text = 'Length';
            app.LengthSpinner = uispinner(app.MotionBlurringTab);
            app.LengthSpinner.Step = 0.01;
            app.LengthSpinner.Value = 30;
            app.DeblurringTab = uitab(app.TabGroup3_2);
            app.DeblurringTab.Title = 'Deblurring';
            app.BlurDropDownLabel = uilabel(app.DeblurringTab);
            app.BlurDropDownLabel.Text = 'Blur';
            app.BlurDropDown = uidropdown(app.DeblurringTab);
            app.BlurDropDown.Items = {'Motion', 'Gaussian'};
            app.BlurDropDown.ValueChangedFcn = createCallbackFcn(app, @BlurDropDownValueChanged, true);
            app.BlurDropDown.Value = 'Motion';
            app.ThetaSpinner_2Label = uilabel(app.DeblurringTab);
            app.ThetaSpinner_2Label.Text = 'Theta';
            app.ThetaSpinner_2 = uispinner(app.DeblurringTab);
            app.ThetaSpinner_2.Step = 0.01;
            app.ThetaSpinner_2.Value = 45;
            app.LengthSpinner_2Label = uilabel(app.DeblurringTab);
            app.LengthSpinner_2Label.Text = 'Length';
            app.LengthSpinner_2 = uispinner(app.DeblurringTab);
            app.LengthSpinner_2.Step = 0.01;
            app.LengthSpinner_2.Value = 30;
            app.SpinnerLabel_2 = uilabel(app.DeblurringTab);
            app.SpinnerLabel_2.Enable = 'off';
            app.SpinnerLabel_2.Text = 'σ';
            app.SigmaSpinner_2 = uispinner(app.DeblurringTab);
            app.SigmaSpinner_2.Step = 0.01;
            app.SigmaSpinner_2.Limits = [0.01 Inf];
            app.SigmaSpinner_2.Enable = 'off';
            app.SigmaSpinner_2.Value = 2.5;
            app.MaskDimensionSpinner_3Label = uilabel(app.DeblurringTab);
            app.MaskDimensionSpinner_3Label.Enable = 'off';
            app.MaskDimensionSpinner_3Label.Text = 'Mask Dimension';
            app.MaskDimensionSpinner_3 = uispinner(app.DeblurringTab);
            app.MaskDimensionSpinner_3.Step = 2;
            app.MaskDimensionSpinner_3.Limits = [3 Inf];
            app.MaskDimensionSpinner_3.Enable = 'off';
            app.MaskDimensionSpinner_3.Value = 21;
            app.NoiseVarianceSpinnerLabel = uilabel(app.DeblurringTab);
            app.NoiseVarianceSpinnerLabel.Text = 'Noise Variance';
            app.NoiseVarianceSpinner = uispinner(app.DeblurringTab);
            app.NoiseVarianceSpinner.Step = 0.0001;
            app.NoiseVarianceSpinner.Limits = [0 Inf];
            app.NoiseVarianceSpinner.Value = 0.0001;
            app.InsertBlurryImageButton = uibutton(app.ImageDeblurringTab, 'push');
            app.InsertBlurryImageButton.ButtonPushedFcn = createCallbackFcn(app, @InsertBlurryImageButtonPushed, true);
            app.InsertBlurryImageButton.Text = 'Insert Blurry Image';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end